classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PolesZerosButton_2         matlab.ui.control.Button
        SelectDomainButtonGroup    matlab.ui.container.ButtonGroup
        FrequencyButton            matlab.ui.control.ToggleButton
        TimeButton                 matlab.ui.control.ToggleButton
        ResetButton                matlab.ui.control.Button
        plotMagphButton            matlab.ui.control.Button
        ApplyAllButton             matlab.ui.control.Button
        LoadButton                 matlab.ui.control.Button
        ApplyButton                matlab.ui.control.Button
        FiltersButtonGroup         matlab.ui.container.ButtonGroup
        EllipticButton             matlab.ui.control.RadioButton
        ChebyshevtypeIIButton      matlab.ui.control.RadioButton
        ChebyshevtypeIButton       matlab.ui.control.RadioButton
        ButterworthButton          matlab.ui.control.RadioButton
        ECGSignalEnhancementLabel  matlab.ui.control.Label
        UIAxes5                    matlab.ui.control.UIAxes
        UIAxes4                    matlab.ui.control.UIAxes
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data                       % Table to store ECG data
        time                       % Time vector
        ecg_signal                 % ECG signal vector
        filtered_signal            % Filtered ECG signal
        variable

        % here are the variables after applied filters
        ecg_butt
        ecg_cheb1
        ecg_cheb2
        ecg_ellip
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            disp('Its working')
            [file, path] = uigetfile('C:\Users\Hp\Downloads\Matlab-Project\*.csv');
            if isequal(file, 0)
                disp('File selection canceled');
            else
                fullFileName = fullfile(path, file);
                app.data = readtable(fullFileName);
                app.time = app.data.('Time_s_');
                app.ecg_signal = app.data.('Channel1_V_');
                
                % Plot original ECG signal
                plot(app.UIAxes, app.time, app.ecg_signal);
                title(app.UIAxes, 'Original ECG Signal');
                xlabel(app.UIAxes, 'Time (s)');
                ylabel(app.UIAxes, 'Amplitude (V)');
                grid(app.UIAxes, 'on');
            end
        end

        % Button pushed function: ApplyButton
        function ApplyFilterButtonPushed(app, event)
            % Apply the selected filter based on the button group selection
            if event.Source == app.ApplyButton
                filterType = app.FiltersButtonGroup.SelectedObject.Text;
            elseif event.Source == app.ApplyButton_2
                filterType = app.HzButtonGroup_2.SelectedObject.Text;
            else
                filterType = app.above45HzButtonGroup.SelectedObject.Text;
            end
            
            app.variable = filterType;
            fs = 200;  % Sampling frequency

            if app.TimeButton.Value %Time Domain
                % Plot original ECG signal
                plot(app.UIAxes, app.time, app.ecg_signal);
                title(app.UIAxes, 'Original ECG Signal');
                xlabel(app.UIAxes, 'Time (s)');
                ylabel(app.UIAxes, 'Amplitude (V)');
                grid(app.UIAxes, 'on');

                % Define filter specifications
                Wp = [6 40] / (fs / 2);  % Passband edge frequencies (normalized)
                Ws = [1 45] / (fs / 2);  % Stopband edge frequencies (normalized)
                Rp = 1;   % Passband ripple in dB
                Rs = 40;  % Stopband attenuation in dB
                
                switch filterType
                    case 'Butterworth'
                        [n, Wn] = buttord(Wp, Ws, Rp, Rs);
                        [b, a] = butter(n, Wn);
                    case 'Chebyshev type I'
                        [n, Wp] = cheb1ord(Wp, Ws, Rp, Rs);
                        [b, a] = cheby1(n, Rp, Wp);
                    case 'Chebyshev type II'
                        [n, Ws] = cheb2ord(Wp, Ws, Rp, Rs);
                        [b, a] = cheby2(n, Rs, Ws);
                    case 'Elliptic'
                        [n, Wp] = ellipord(Wp, Ws, Rp, Rs);
                        [b, a] = ellip(n, Rp, Rs, Wp);
                end
                
                % Apply the filter
                app.filtered_signal = filtfilt(b, a, app.ecg_signal);
                
                % Plot the filtered ECG signal
                plot(app.UIAxes2, app.time,(app.ecg_signal- app.filtered_signal));
                title(app.UIAxes2, 'Filtered ECG Signal');
                xlabel(app.UIAxes2, 'Time (s)');
                ylabel(app.UIAxes2, 'Amplitude (V)');
                grid(app.UIAxes2, 'on');

            else %Freq Domain
                n = length(app.ecg_signal);
                f = (0:n-1)*(fs/n);

                % Original ECG signal
                fft_orig = fft(app.ecg_signal);
                mag_orig = abs(fft_orig);
                phase_orig = angle(fft_orig);

                % Shift frequencies
                f_shifted = fftshift(f);

                % Plot the magnitude response of the original signal
                plot(app.UIAxes, f_shifted, mag_orig, 'k', 'DisplayName', 'Original');
                title(app.UIAxes, 'Magnitude Response - Original ECG Signal');
                xlabel(app.UIAxes, 'Frequency (Hz)');
                ylabel(app.UIAxes, 'Magnitude');
                grid(app.UIAxes, 'on');

                %plotting phase response of the original signal
                plot(app.UIAxes3, f_shifted, unwrap(phase_orig), 'r', 'DisplayName', 'Original');
                title(app.UIAxes3, 'Phase Responses - Original Signal');
                xlabel(app.UIAxes3, 'Frequency (Hz)');
                ylabel(app.UIAxes3, 'Phase (radians)');
                grid(app.UIAxes3, 'on');

                switch filterType
                    case 'Butterworth'
                        % Butterworth filtered ECG signal
                        fft_x = fft(app.ecg_butt);
                        mag_y = abs(fft_x);
                        phase_z = angle(fft_x);
                    case 'Chebyshev type I'
                        % Chebyshev Type I filtered ECG signal
                        fft_x = fft(app.ecg_cheb1);
                        mag_y = abs(fft_x);
                        phase_z = angle(fft_x);
                    case 'Chebyshev type II'
                        % Chebyshev Type II filtered ECG signal
                        fft_x = fft(app.ecg_cheb2);
                        mag_y = abs(fft_x);
                        phase_z = angle(fft_x);
                    case 'Elliptic'
                        % Elliptic filtered ECG signal
                        fft_x = fft(app.ecg_ellip);
                        mag_y = abs(fft_x);
                        phase_z = angle(fft_x);
                end
                %plotting mag response
                plot(app.UIAxes2, f_shifted, mag_orig, 'k', 'DisplayName', 'Original');
                hold (app.UIAxes2, 'on');
                plot(app.UIAxes2, f_shifted, mag_y, 'r', 'DisplayName', filterType);
                hold (app.UIAxes2, 'off');
                title(app.UIAxes2, sprintf('Magnitude Responses - %s', filterType));
                xlabel(app.UIAxes2, 'Frequency (Hz)');
                ylabel(app.UIAxes2, 'Magnitude');
                grid(app.UIAxes2, 'on');

                %plotting filter phase response
                plot(app.UIAxes4, f_shifted, unwrap(phase_z), 'r', 'DisplayName', filterType);
                title(app.UIAxes4, sprintf('Phase Responses - %s', filterType));
                xlabel(app.UIAxes4, 'Frequency (Hz)');
                ylabel(app.UIAxes4, 'Phase (radians)');
                grid(app.UIAxes4, 'on');
            end

            
        end

        % Button pushed function: ApplyAllButton
        function ApplyAllButtonPushed(app, event)
            % Apply all filters
            fs = 200;  % Sampling frequency
            Wp = [6 40] / (fs / 2);  % Passband edge frequencies (normalized)
            Ws = [1 45] / (fs / 2);  % Stopband edge frequencies (normalized)
            Rp = 1;   % Passband ripple in dB
            Rs = 40;  % Stopband attenuation in dB
            
            % Design filters
            [n_butt, Wn_butt] = buttord(Wp, Ws, Rp, Rs);
            [b_butt, a_butt] = butter(n_butt, Wn_butt);
            
            [n_cheb1, Wp_cheb1] = cheb1ord(Wp, Ws, Rp, Rs);
            [b_cheb1, a_cheb1] = cheby1(n_cheb1, Rp, Wp_cheb1);
            
            [n_cheb2, Ws_cheb2] = cheb2ord(Wp, Ws, Rp, Rs);
            [b_cheb2, a_cheb2] = cheby2(n_cheb2, Rs, Ws_cheb2);
            
            [n_ellip, Wp_ellip] = ellipord(Wp, Ws, Rp, Rs);
            [b_ellip, a_ellip] = ellip(n_ellip, Rp, Rs, Wp_ellip);
            
            % Apply filters
            app.ecg_butt = filtfilt(b_butt, a_butt, app.ecg_signal);
            app.ecg_cheb1 = filtfilt(b_cheb1, a_cheb1, app.ecg_signal);
            app.ecg_cheb2 = filtfilt(b_cheb2, a_cheb2, app.ecg_signal);
            app.ecg_ellip = filtfilt(b_ellip, a_ellip, app.ecg_signal);
            
            plot(app.UIAxes2, app.time, app.ecg_butt, app.time, app.ecg_cheb1, app.time, app.ecg_cheb2, app.time, app.ecg_ellip);
            title(app.UIAxes2, 'Filtered ECG Signals');
            xlabel(app.UIAxes2, 'Time (s)');
            ylabel(app.UIAxes2, 'Amplitude (V)');
            legend(app.UIAxes2, 'Butterworth', 'Chebyshev I', 'Chebyshev II', 'Elliptic');
            grid(app.UIAxes2, 'on');
        end

        % Button pushed function: plotMagphButton
        function plotMagphButtonPushed(app, event)
            fs = 200;  % Sampling frequency
            Wp = [6 40] / (fs / 2);  % Passband edge frequencies (normalized)
            Ws = [1 45] / (fs / 2);  % Stopband edge frequencies (normalized)
            Rp = 1;   % Passband ripple in dB
            Rs = 40;  % Stopband attenuation in dB
            
            % Get filter coefficients for selected filter
            switch app.variable
                case 'Butterworth'
                    [n, Wn] = buttord(Wp, Ws, Rp, Rs);
                    [b, a] = butter(n, Wn);
                case 'Chebyshev type I'
                    [n, Wp] = cheb1ord(Wp, Ws, Rp, Rs);
                    [b, a] = cheby1(n, Rp, Wp);
                case 'Chebyshev type II'
                    [n, Ws] = cheb2ord(Wp, Ws, Rp, Rs);
                    [b, a] = cheby2(n, Rs, Ws);
                case 'Elliptic'
                    [n, Wp] = ellipord(Wp, Ws, Rp, Rs);
                    [b, a] = ellip(n, Rp, Rs, Wp);
            end
            
            % Frequency response
            [H, w] = freqz(b, a);
            
            % Plot magnitude response
            plot(app.UIAxes3, w/pi*fs/2, 20*log10(abs(H)));
            title(app.UIAxes3, 'Magnitude Response');
            xlabel(app.UIAxes3, 'Frequency (Hz)');
            ylabel(app.UIAxes3, 'Magnitude (dB)');
            grid(app.UIAxes3, 'on');
            
            % Plot phase response
            plot(app.UIAxes4, w/pi*fs/2, angle(H));
            title(app.UIAxes4, 'Phase Response');
            xlabel(app.UIAxes4, 'Frequency (Hz)');
            ylabel(app.UIAxes4, 'Phase (radians)');
            grid(app.UIAxes4, 'on');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.UIAxes5);
        end

        % Button pushed function: PolesZerosButton_2
        function PolesZerosButton(app, event)
            fs = 200;  % Sampling frequency
            Wp = [6 40] / (fs / 2);  % Passband edge frequencies (normalized)
            Ws = [1 45] / (fs / 2);  % Stopband edge frequencies (normalized)
            Rp = 1;   % Passband ripple in dB
            Rs = 40;  % Stopband attenuation in dB
            
            % Get filter coefficients for selected filter
            switch app.variable
                case 'Butterworth'
                    [n, Wn] = buttord(Wp, Ws, Rp, Rs);
                    [b, a] = butter(n, Wn);
                case 'Chebyshev type I'
                    [n, Wp] = cheb1ord(Wp, Ws, Rp, Rs);
                    [b, a] = cheby1(n, Rp, Wp);
                case 'Chebyshev type II'
                    [n, Ws] = cheb2ord(Wp, Ws, Rp, Rs);
                    [b, a] = cheby2(n, Rs, Ws);
                case 'Elliptic'
                    [n, Wp] = ellipord(Wp, Ws, Rp, Rs);
                    [b, a] = ellip(n, Rp, Rs, Wp);
            end
            
            % Plot the poles and zeros
            z = roots(b);
            p = roots(a);

            % Clear previous plot
            cla(app.UIAxes5);
            hold(app.UIAxes5, 'on');
        
            % Plot unit circle
            theta = linspace(0, 2*pi, 100);
            plot(app.UIAxes5, cos(theta), sin(theta), 'k--');

            % Plot poles and zeros
            plot(app.UIAxes5, real(z), imag(z), 'bo', 'MarkerSize', 8, 'LineWidth', 2);
            plot(app.UIAxes5, real(p), imag(p), 'rx', 'MarkerSize', 8, 'LineWidth', 2);
            
            % Set axes limits and labels
            axis(app.UIAxes5, 'equal');
            %xlim(app.UIAxes3, [-1.5 1.5]);
            %ylim(app.UIAxes3, [-1.5 1.5]);
            xlabel(app.UIAxes5, 'Real Part');
            ylabel(app.UIAxes5, 'Imaginary Part');
            title(app.UIAxes5, 'Pole-Zero Diagram');
            grid(app.UIAxes5, 'on');
            hold(app.UIAxes5, 'off');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Signal')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Output Signal')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Magnitude Plot')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Phase Plot')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Poles & Zeros')
            app.ECGSignalEnhancementLabel = uilabel(app.UIFigure);
            app.ECGSignalEnhancementLabel.Text = 'ECG Signal Enhancement';
            app.FiltersButtonGroup = uibuttongroup(app.UIFigure);
            app.FiltersButtonGroup.Title = 'Filters';
            app.ButterworthButton = uiradiobutton(app.FiltersButtonGroup);
            app.ButterworthButton.Text = 'Butterworth';
            app.ButterworthButton.Value = true;
            app.ChebyshevtypeIButton = uiradiobutton(app.FiltersButtonGroup);
            app.ChebyshevtypeIButton.Text = 'Chebyshev type I';
            app.ChebyshevtypeIIButton = uiradiobutton(app.FiltersButtonGroup);
            app.ChebyshevtypeIIButton.Text = 'Chebyshev type II';
            app.EllipticButton = uiradiobutton(app.FiltersButtonGroup);
            app.EllipticButton.Text = 'Elliptic';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ApplyAllButton = uibutton(app.UIFigure, 'push');
            app.ApplyAllButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyAllButtonPushed, true);
            app.ApplyAllButton.Text = 'Apply All';
            app.plotMagphButton = uibutton(app.UIFigure, 'push');
            app.plotMagphButton.ButtonPushedFcn = createCallbackFcn(app, @plotMagphButtonPushed, true);
            app.plotMagphButton.Text = 'plot Mag & ph';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SelectDomainButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectDomainButtonGroup.Title = 'Select Domain';
            app.TimeButton = uitogglebutton(app.SelectDomainButtonGroup);
            app.TimeButton.Text = 'Time';
            app.TimeButton.Value = true;
            app.FrequencyButton = uitogglebutton(app.SelectDomainButtonGroup);
            app.FrequencyButton.Text = 'Frequency';
            app.PolesZerosButton_2 = uibutton(app.UIFigure, 'push');
            app.PolesZerosButton_2.ButtonPushedFcn = createCallbackFcn(app, @PolesZerosButton, true);
            app.PolesZerosButton_2.Text = 'Poles & Zeros';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end